classdef Checklist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChecklistUIFigure               matlab.ui.Figure
        NopersonisintherobotcellandthelightcurtainisactivatedCheckBox  matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        ConveyorguardsisclosedCheckBox  matlab.ui.control.CheckBox
        ModeswitchissettoManualCheckBox  matlab.ui.control.CheckBox
        AllEmergencyStopsareoffCheckBox  matlab.ui.control.CheckBox
        ChecktherobotarmisnotobstructedCheckBox  matlab.ui.control.CheckBox
        CheckItemSixCheckBox            matlab.ui.control.CheckBox
        Lamp_2                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        SafetyStartupChecklistLabel     matlab.ui.control.Label
        Knob                            matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        Property % Description
        CheckList = 0; 
        
    end
    
    methods (Access = private)
        function  isDone(app)
            disp(app.CheckList);
            if app.CheckList == 63
                app.Knob.Visible = 'on';
            else
                app.Knob.Visible = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: 
        % NopersonisintherobotcellandthelightcurtainisactivatedCheckBox
        function NopersonisintherobotcellandthelightcurtainisactivatedCheckBoxValueChanged(app, event)
            if app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.Value
%                 app.Lamp.Enable = 'on';
                app.Lamp.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 1, 'uint8');
            else
%                 app.Lamp.Enable = 'off';
                app.Lamp.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 1, 0);
            end
            
            isDone(app);
        end

        % Value changed function: ConveyorguardsisclosedCheckBox
        function ConveyorguardsisclosedCheckBoxValueChanged(app, event)
            if app.ConveyorguardsisclosedCheckBox.Value
                app.Lamp_2.Color = [0, 1, 0];
%                 app.Lamp_2.Enable = 'on';
                app.CheckList = bitor(app.CheckList, 2, 'uint8');
            else
%                 app.Lamp_2.Enable = 'off';
                app.Lamp_2.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 2, 0);
            end
            
            isDone(app);
        end

        % Value changed function: ModeswitchissettoManualCheckBox
        function ModeswitchissettoManualCheckBoxValueChanged(app, event)
            if app.ModeswitchissettoManualCheckBox.Value
%                 app.Lamp_3.Enable = 'on';
                app.Lamp_3.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 4, 'uint8');
            else
%                 app.Lamp_3.Enable = 'off';
                app.Lamp_3.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 3, 0);
            end    
            
            isDone(app);
        end

        % Value changed function: AllEmergencyStopsareoffCheckBox
        function AllEmergencyStopsareoffCheckBoxValueChanged(app, event)
            if app.AllEmergencyStopsareoffCheckBox.Value
%                 app.Lamp_4.Enable = 'on';
                app.Lamp_4.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 8, 'uint8');
            else
%                 app.Lamp_4.Enable = 'off';
                app.Lamp_4.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 4, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: 
        % ChecktherobotarmisnotobstructedCheckBox
        function ChecktherobotarmisnotobstructedCheckBoxValueChanged(app, event)
            if app.ChecktherobotarmisnotobstructedCheckBox.Value
%                 app.Lamp_5.Enable = 'on';
                app.Lamp_5.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 16, 'uint8');
            else
%                 app.Lamp_5.Enable = 'off';
                app.Lamp_5.Color = [1, 0 , 0];
                app.CheckList = bitset(app.CheckList, 5, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: CheckItemSixCheckBox
        function CheckItemSixCheckBoxValueChanged(app, event)
            if app.CheckItemSixCheckBox.Value
%                 app.Lamp_6.Enable = 'on';
                app.Lamp_6.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 32, 'uint8');
            else
%                 app.Lamp_6.Enable = 'off';
                app.Lamp_6.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 6, 0);
            end  
            
            isDone(app);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            if app.Knob.Value == "On"
                Main2
                delete(app.ChecklistUIFigure);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChecklistUIFigure = uifigure('Visible', 'off');
            app.ChecklistUIFigure.Name = 'Checklist';
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NopersonisintherobotcellandthelightcurtainisactivatedCheckBoxValueChanged, true);
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.Text = 'No person is in the robot cell and the light curtain is activated';
            app.Lamp = uilamp(app.ChecklistUIFigure);
            app.ConveyorguardsisclosedCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.ConveyorguardsisclosedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConveyorguardsisclosedCheckBoxValueChanged, true);
            app.ConveyorguardsisclosedCheckBox.Text = 'Conveyor guards is closed';
            app.ModeswitchissettoManualCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.ModeswitchissettoManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModeswitchissettoManualCheckBoxValueChanged, true);
            app.ModeswitchissettoManualCheckBox.Text = 'Mode switch is set to Manual';
            app.AllEmergencyStopsareoffCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.AllEmergencyStopsareoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllEmergencyStopsareoffCheckBoxValueChanged, true);
            app.AllEmergencyStopsareoffCheckBox.Text = 'All Emergency Stops are off';
            app.ChecktherobotarmisnotobstructedCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.ChecktherobotarmisnotobstructedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChecktherobotarmisnotobstructedCheckBoxValueChanged, true);
            app.ChecktherobotarmisnotobstructedCheckBox.Text = 'Check the robot arm is not obstructed';
            app.CheckItemSixCheckBox = uicheckbox(app.ChecklistUIFigure);
            app.CheckItemSixCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckItemSixCheckBoxValueChanged, true);
            app.CheckItemSixCheckBox.Text = 'Check Item Six';
            app.Lamp_2 = uilamp(app.ChecklistUIFigure);
            app.Lamp_3 = uilamp(app.ChecklistUIFigure);
            app.Lamp_4 = uilamp(app.ChecklistUIFigure);
            app.Lamp_5 = uilamp(app.ChecklistUIFigure);
            app.Lamp_6 = uilamp(app.ChecklistUIFigure);
            app.SafetyStartupChecklistLabel = uilabel(app.ChecklistUIFigure);
            app.SafetyStartupChecklistLabel.Text = 'Safety Startup Checklist';
            app.Knob = uiknob(app.ChecklistUIFigure, 'discrete');
            app.Knob.Items = {'Off', 'On'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Visible = 'off';
            % Show the figure after all components are created
            app.ChecklistUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Checklist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChecklistUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChecklistUIFigure)
        end
    end
end