classdef ESTOP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Emergency_STOP  matlab.ui.Figure
        Lamp            matlab.ui.control.Lamp
        ESTOPLabel      matlab.ui.control.Label
        NopersonisintherobotcellandthelightcurtainisactivatedCheckBox  matlab.ui.control.CheckBox
        Lamp_2          matlab.ui.control.Lamp
        RestockicingandchocolateblocksCheckBox  matlab.ui.control.CheckBox
        MakesurethatallemergencystopbuttonsareresetCheckBox  matlab.ui.control.CheckBox
        MakesurethatboththeRobotandconveyorarepoweredCheckBox  matlab.ui.control.CheckBox
        RestockicingandchocolateblocksCheckBox_2  matlab.ui.control.CheckBox
        Lamp_3          matlab.ui.control.Lamp
        Lamp_4          matlab.ui.control.Lamp
        Lamp_5          matlab.ui.control.Lamp
        Lamp_6          matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        Timer % Description
        Toggle
        CheckList;
    end
    
    methods (Access = private)
        
        function LightToggle(app, ~, ~)
            if app.Toggle == 1
                app.Lamp.Color = [1 0 0];
                app.Toggle = 0;
            else
                app.Lamp.Color = [0.7098 0 0];
                app.Toggle = 1;
            end
        end
        
        function  isDone(app)
            disp(app.CheckList);
            if app.CheckList == 31
                app.CONTINUESwitch.Enable = 'on';
            else
                app.CONTINUESwitch.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(timerfind);
            app.Toggle = 1;
            app.CheckList = 0; 
            
            app.Timer = timer('StartDelay', 1, 'Period', 0.5, 'ExecutionMode', 'fixedRate', 'TasksToExecute', Inf);
            app.Timer.TimerFcn = @(obj,event)app.LightToggle(app);
            start(app.Timer);
        end

        % Value changed function: 
        % NopersonisintherobotcellandthelightcurtainisactivatedCheckBox
        function NopersonisintherobotcellandthelightcurtainisactivatedCheckBoxValueChanged(app, event)
            value = app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.Value;
            if value 
                app.Lamp_2.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 1, 'uint8');
            else
                app.Lamp_2.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 1, 0);
            end
            isDone(app);
        end

        % Value changed function: 
        % RestockicingandchocolateblocksCheckBox
        function RestockicingandchocolateblocksCheckBoxValueChanged(app, event)
            value = app.RestockicingandchocolateblocksCheckBox.Value;
            if value 
                app.Lamp_3.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 2, 'uint8');
            else
                app.Lamp_3.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 2, 0);
            end
            isDone(app);
        end

        % Value changed function: 
        % MakesurethatallemergencystopbuttonsareresetCheckBox
        function MakesurethatallemergencystopbuttonsareresetCheckBoxValueChanged(app, event)
            value = app.MakesurethatallemergencystopbuttonsareresetCheckBox.Value;
            if value 
                app.Lamp_4.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 4, 'uint8');
            else
                app.Lamp_4.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 3, 0);
            end
            isDone(app);
        end

        % Value changed function: 
        % MakesurethatboththeRobotandconveyorarepoweredCheckBox
        function MakesurethatboththeRobotandconveyorarepoweredCheckBoxValueChanged(app, event)
            value = app.MakesurethatboththeRobotandconveyorarepoweredCheckBox.Value;
            if value 
                app.Lamp_5.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 8, 'uint8');
            else
                app.Lamp_5.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 4, 0);
            end
            isDone(app);
        end

        % Value changed function: 
        % RestockicingandchocolateblocksCheckBox_2
        function RestockicingandchocolateblocksCheckBox_2ValueChanged(app, event)
            value = app.RestockicingandchocolateblocksCheckBox_2.Value;
            if value 
                app.Lamp_6.Color = [0, 1, 0];
                app.CheckList = bitor(app.CheckList, 16, 'uint8');
            else
                app.Lamp_6.Color = [1, 0, 0];
                app.CheckList = bitset(app.CheckList, 5, 0);
            end
            isDone(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Emergency_STOP = uifigure('Visible', 'off');
            app.Emergency_STOP.Name = 'ESTOP';
            app.Lamp = uilamp(app.Emergency_STOP);
            app.ESTOPLabel = uilabel(app.Emergency_STOP);
            app.ESTOPLabel.Text = 'E-STOP';
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox = uicheckbox(app.Emergency_STOP);
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NopersonisintherobotcellandthelightcurtainisactivatedCheckBoxValueChanged, true);
            app.NopersonisintherobotcellandthelightcurtainisactivatedCheckBox.Text = {'Check that guards for the robot and conveyor have been installed'; ' properly'};
            app.Lamp_2 = uilamp(app.Emergency_STOP);
            app.RestockicingandchocolateblocksCheckBox = uicheckbox(app.Emergency_STOP);
            app.RestockicingandchocolateblocksCheckBox.ValueChangedFcn = createCallbackFcn(app, @RestockicingandchocolateblocksCheckBoxValueChanged, true);
            app.RestockicingandchocolateblocksCheckBox.Text = 'Restock icing and chocolate blocks';
            app.MakesurethatallemergencystopbuttonsareresetCheckBox = uicheckbox(app.Emergency_STOP);
            app.MakesurethatallemergencystopbuttonsareresetCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakesurethatallemergencystopbuttonsareresetCheckBoxValueChanged, true);
            app.MakesurethatallemergencystopbuttonsareresetCheckBox.Text = 'Make sure that all emergency stop buttons are reset';
            app.MakesurethatboththeRobotandconveyorarepoweredCheckBox = uicheckbox(app.Emergency_STOP);
            app.MakesurethatboththeRobotandconveyorarepoweredCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakesurethatboththeRobotandconveyorarepoweredCheckBoxValueChanged, true);
            app.MakesurethatboththeRobotandconveyorarepoweredCheckBox.Text = 'Make sure that both the Robot and conveyor are powered';
            app.RestockicingandchocolateblocksCheckBox_2 = uicheckbox(app.Emergency_STOP);
            app.RestockicingandchocolateblocksCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @RestockicingandchocolateblocksCheckBox_2ValueChanged, true);
            app.RestockicingandchocolateblocksCheckBox_2.Text = {'Check to make sure that all operators and personal are standing outside '; 'and that the interlocked doors are closed properly'};
            app.Lamp_3 = uilamp(app.Emergency_STOP);
            app.Lamp_4 = uilamp(app.Emergency_STOP);
            app.Lamp_5 = uilamp(app.Emergency_STOP);
            app.Lamp_6 = uilamp(app.Emergency_STOP);
            % Show the figure after all components are created
            app.Emergency_STOP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESTOP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Emergency_STOP)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Emergency_STOP)
        end
    end
end