classdef Main2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OrderTab                      matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        STARTButton_2                 matlab.ui.control.Button
        InkPrintingLampLabel          matlab.ui.control.Label
        InkPrintingLamp               matlab.ui.control.Lamp
        CakeDecorationLampLabel       matlab.ui.control.Label
        CakeDecorationLamp            matlab.ui.control.Lamp
        TextArea_2                    matlab.ui.control.TextArea
        UIAxes_2                      matlab.ui.control.UIAxes
        ErrorLampLabel                matlab.ui.control.Label
        ErrorLamp                     matlab.ui.control.Lamp
        SPEEDLabel                    matlab.ui.control.Label
        mmsLabel                      matlab.ui.control.Label
        DemoTab                       matlab.ui.container.Tab
        TableCamera                   matlab.ui.control.UIAxes
        ConveyorCamera                matlab.ui.control.UIAxes
        Notifications                 matlab.ui.container.Panel
        LampLabel                     matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Lamp2Label                    matlab.ui.control.Label
        Lamp2                         matlab.ui.control.Lamp
        Lamp3Label                    matlab.ui.control.Label
        Lamp3                         matlab.ui.control.Lamp
        Lamp4Label                    matlab.ui.control.Label
        Lamp4                         matlab.ui.control.Lamp
        Lamp5Label                    matlab.ui.control.Label
        Lamp5                         matlab.ui.control.Lamp
        Lamp6Label                    matlab.ui.control.Label
        Lamp6                         matlab.ui.control.Lamp
        Controls                      matlab.ui.container.Panel
        VacuumLabel                   matlab.ui.control.Label
        ConveyorLabel                 matlab.ui.control.Label
        ConveyorDirectionButtonGroup  matlab.ui.container.ButtonGroup
        TowardsButton                 matlab.ui.control.RadioButton
        AwayButton                    matlab.ui.control.RadioButton
        SOLENOIDButton                matlab.ui.control.Button
        STARTButton                   matlab.ui.control.StateButton
        SolenoidButtonGroup           matlab.ui.container.ButtonGroup
        OffButton                     matlab.ui.control.RadioButton
        OnButton                      matlab.ui.control.RadioButton
        POWERButton_2                 matlab.ui.control.StateButton
        ConsolePanel                  matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        CameraTab                     matlab.ui.container.Tab
        TableCameraPanel              matlab.ui.container.Panel
        TCam                          matlab.ui.control.UIAxes
        ConveyorCameraPanel           matlab.ui.container.Panel
        CCam                          matlab.ui.control.UIAxes
        Status                        matlab.ui.control.Lamp
        Knob                          matlab.ui.control.DiscreteKnob
        ROBOTSTATELabel               matlab.ui.control.Label
        TASKLabel                     matlab.ui.control.Label
        CONNECTIONLabel               matlab.ui.control.Label
        DISCONNECTEDLabel             matlab.ui.control.Label
        IDLELabel                     matlab.ui.control.Label
        WAITINGLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Camera objects
        TableCam;
        TableCamRes;
        TableCamBands;
        TableCamSnap;
        TableCamImg;
        TableCamPrev;
        
        ConvCam;
        ConvCamRes;
        ConvCamBands;
        ConvCamSnap;
        ConvCamImg;
        ConvCamPrev;
        
        % IO states 
        Vacuum = 0; 
        Sol = 0;
        Conv = 0;
        ConvDir = 0;  
        
        % Connection
        Robot_IP = '192.168.125.1';
        Simulate_IP = '127.0.0.1'; 
        PORT = 1025;
        Connected = 0;


        %Timer stuff
        Timer;
        ConveyorTimer;
    end
    
    properties (Access = public)
        % Camera Objects
        SnapTable;
        SnapConv;
        
        % Cake Decorations
        InkCharacters;
        
        % IO Status
        iostatus = 1;
        
        Special;
        
        ErrorMsg;
        ErrorFlag;
        
        % IO Connection related stuff
        data;
        Socket;
        Commands = [];
        temp;
        recieved;
        ConType;

        
        % Console incrimenter
        consolecount = 0;


    end
    
    methods (Access = private)
        
        function videos(app)
            if app.ConType == 1
                imshow('testImage.jpg', 'Parent',...
                    app.UIAxes);
                imshow('Conveyor6.jpg', 'Parent',...
                    app.UIAxes_2);
                imshow('testImage.jpg', 'Parent',...
                    app.TableCamera);
                imshow('Conveyor6.jpg', 'Parent',...
                    app.ConveyorCamera);
            elseif app.ConType == 0
                % Table Cam
                app.TableCam = videoinput('winvideo', 1, 'MJPG_1600x1200');
                app.TableCamRes = app.TableCam.VideoResolution;
                app.TableCamBands = app.TableCam.NumberOfBands;
                app.TableCamImg = imshow(zeros([app.TableCamRes(2), app.TableCamRes(1), app.TableCamBands]),...
                    'Parent', app.UIAxes);
%                 app.TableCamImg = imshow(zeros([app.TableCamRes(2), app.TableCamRes(1), app.TableCamBands]),...
%                     'Parent', app.TableCamera);
                app.TableCamPrev = preview(app.TableCam, app.TableCamImg);
                src = getselectedsource(app.TableCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
                
                % Conveyor Cam
                app.ConvCam = videoinput('winvideo', 2, 'MJPG_1600x1200');
                app.ConvCamRes = app.ConvCam.VideoResolution;
                app.ConvCamBands = app.ConvCam.NumberOfBands;
                app.ConvCamImg = imshow(zeros([app.ConvCamRes(2), app.ConvCamRes(1), app.ConvCamBands]),...
                    'Parent', app.UIAxes_2);
                app.ConvCamPrev = preview(app.ConvCam, app.ConvCamImg);
                src = getselectedsource(app.ConvCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
            end
        end
        
        function main(app,~,~)
            
            if app.Connected == 0
                app.DISCONNECTEDLabel.Text = 'RECONNECTING...';
                app.TextArea_2.Value{app.consolecount} = 'Attempting to reconnect...';
                app.TextArea.Value{app.consolecount} = 'Attempting to reconnect...';
                app.consolecount = app.consolecount + 1;
                app.DISCONNECTEDLabel.FontColor = [1, 0.41, 0.16];
                [app.Socket, app.ConType] = connect();
                app.Connected = 1;
                checkConnection(app);
            end
            
            
            if app.Connected == 1
                    if ~isempty(app.Commands)
                        app.recieved = SendCommand(app);
                        if app.recieved ~= -1
                            app.Commands(1) = [];
                        end
                        app.recieved = ParseMessage(app);
                    end
            end
        end
        
        function checkConnection(app)
            if app.ConType == 0
                app.TextArea_2.Value{app.consolecount} = 'Connected to Robot';
                app.TextArea.Value{app.consolecount} = 'Connected to Robot';
                app.DISCONNECTEDLabel.Text = 'CONNECTED';
                app.DISCONNECTEDLabel.FontColor = [0, 1, 0];
                app.Connected = 1;
            elseif app.ConType == 1
                app.TextArea_2.Value{app.consolecount} = 'Connected to Simulator';
                app.TextArea.Value{app.consolecount} = 'Connected to Simulator';
                app.DISCONNECTEDLabel.Text = 'CONNECTED';
                app.DISCONNECTEDLabel.FontColor = [0, 1, 1];
                app.Connected = 1;
            else
                app.TextArea_2.Value{app.consolecount} = 'Disconnected';
                app.TextArea.Value{app.consolecount} = 'Disconnected';
                app.DISCONNECTEDLabel.Text = 'DISCONNECTED';
                app.DISCONNECTEDLabel.FontColor = [1, 0, 0];
                app.Connected = 0;
            end
            app.consolecount = app.consolecount + 1;
        end
        
        function  GetUpdate(app)
            app.SnapConv = getsnapshot(app.ConvCam);
        end
    end
    
    methods (Access = public)
        
        function UpdateConsole(app, value)
            app.TextArea_2.Value{app.consolecount} = value;
            app.TextArea.Value{app.consolecount} = value;
            app.consolecount = app.consolecount + 1;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.TextArea_2.Value = 'Connecting...';
            app.TextArea.Value = 'Connecting...';
            app.consolecount = app.consolecount + 1;
            app.DISCONNECTEDLabel.Text = 'CONNECTING...';
            app.DISCONNECTEDLabel.FontColor = [1, 0.41, 0.16];
            pause(1);
            [app.Socket, app.ConType] = connect();
            checkConnection(app);
                  
            videos(app);
            
            delete(timerfind);
            
            app.Timer = timer('StartDelay', 2, 'Period', 0.5, 'ExecutionMode', 'fixedRate', 'TasksToExecute', Inf,...
                'BusyMode', 'drop');
            app.Timer.TimerFcn = @(obj,event)app.main(app);
            
            start(app.Timer);
                        
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            if app.STARTButton.Value
                app.iostatus = dec2bin(bitset(app.iostatus, 2, 1));
                app.STARTButton.Text = "STOP";
            else
                app.iostatus = dec2bin(bitset(app.iostatus, 2, 0));
                app.STARTButton.Text = "START";
            end
            
            app.temp = CreateCommand(1, app.iostatus);
            app.Commands = QueueCommand(app.Commands, app.temp);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            if value == "OFF"
                app.Status.Color = [1, 0, 0];
                fclose(app.Socket);
                app.Connected = 0;
                delete(timerfind);
            elseif value == "PAUSE"
                app.Status.Color = [1, 0.41, 0.16];
                while true
                end
            elseif value == "ON"
                app.Status.Color = [0, 1, 0];
            end
        end

        % Button pushed function: STARTButton_2
        function STARTButton_2Pushed(app, event)
            if app.ConType == 0
                app.SnapTable = getsnapshot(app.TableCam);
                app.SnapConv = getsnapshot(app.ConvCam);
            end
            app.ConveyorTimer = timer('StartDelay', 0.5, 'Period', 0.5,...
                'ExecutionMode','fixedRate','TasksToExecute',inf,...
                'BusyMode', 'drop');
            app.ConveyorTimer.TimerFcn = @(obj,event)app.GetUpdate(app);
            start(app.ConveyorTimer);
            app.InkCharacters = ImageProcessing_Final(app);
            inkFinal(app);
            app.InkPrintingLamp.Color = [0, 1, 0];
            Main_Ultimate(app);
            app.CakeDecorationLamp.Color = [0, 1, 0];
            delete(app.ConveyorTimer);
        end

        % Close request function: MainUIFigure
        function MainUIFigureCloseRequest(app, event)
            if app.Connected == 1
                fclose(app.Socket);
            end
            
            pause(1);
            delete(app)
            
        end

        % Callback function
        function POWERButtonPushed(app, event)
            
        end

        % Value changed function: POWERButton_2
        function POWERButton_2ValueChanged(app, event)
            value = app.POWERButton_2.Value;
            if value
                app.iostatus = dec2bin(bitset(app.iostatus, 4, 1));
                app.POWERButton_2.Text = "OFF";
            else
                app.iostatus = dec2bin(bitset(app.iostatus, 4, 0));
                app.POWERButton_2.Text = "POWER";
            end
            
            app.temp = CreateCommand(1, num2str(app.iostatus));
            app.Commands = QueueCommand(app.Commands, app.temp);
        end

        % Selection changed function: SolenoidButtonGroup
        function SolenoidButtonGroupSelectionChanged(app, event)
            selectedButton = app.SolenoidButtonGroup.SelectedObject;
            if selectedButton.Text == "OFF"
                app.iostatus = dec2bin(bitset(app.iostatus, 3, 1));
            else
                app.iostatus = dec2bin(bitset(app.iostatus, 3, 0));
            end
            
            app.temp = CreateCommand(1, num2str(app.iostatus));
            app.Commands = QueueCommand(app.Commands, app.temp);
        end

        % Selection changed function: ConveyorDirectionButtonGroup
        function ConveyorDirectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConveyorDirectionButtonGroup.SelectedObject;
            if selectedButton.Text == "Towards"
                app.iostatus = dec2bin(bitset(app.iostatus, 1, 1));
            else
                app.iostatus = dec2bin(bitset(app.iostatus, 1, 0));
            end
            
            app.temp = CreateCommand(1, num2str(app.iostatus));
            app.Commands = QueueCommand(app.Commands, app.temp);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == "DemoTab"
                 app.TableCamImg = imshow(zeros([app.TableCamRes(2), app.TableCamRes(1), app.TableCamBands]),...
                    'Parent', app.TableCamera);
                app.TableCamPrev = preview(app.TableCam, app.TableCamImg);
                src = getselectedsource(app.TableCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
                
                app.ConvCamImg = imshow(zeros([app.ConvCamRes(2), app.ConvCamRes(1), app.ConvCamBands]),...
                    'Parent', app.ConveyorCamera);
                app.ConvCamPrev = preview(app.ConvCam, app.ConvCamImg);
                src = getselectedsource(app.ConvCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
            elseif selectedTab == "CameraTab"
                app.TableCamImg = imshow(zeros([app.TableCamRes(2), app.TableCamRes(1), app.TableCamBands]),...
                    'Parent', app.TCam);
                app.TableCamPrev = preview(app.TableCam, app.TableCamImg);
                src = getselectedsource(app.TableCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
                
                app.ConvCamImg = imshow(zeros([app.ConvCamRes(2), app.ConvCamRes(1), app.ConvCamBands]),...
                    'Parent', app.ConveyorCamera);
                app.ConvCamPrev = preview(app.ConvCam, app.ConvCamImg);
                src = getselectedsource(app.CCam);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
            else
                app.TableCamImg = imshow(zeros([app.TableCamRes(2), app.TableCamRes(1), app.TableCamBands]),...
                    'Parent', app.TCam);
                app.TableCamPrev = preview(app.TableCam, app.TableCamImg);
                src = getselectedsource(app.UIAxes);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
                
                app.ConvCamImg = imshow(zeros([app.ConvCamRes(2), app.ConvCamRes(1), app.ConvCamBands]),...
                    'Parent', app.ConveyorCamera);
                app.ConvCamPrev = preview(app.ConvCam, app.ConvCamImg);
                src = getselectedsource(app.UIAxes_2);
                src.ExposureMode = 'manual';
                src.Exposure = -4;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure('Visible', 'off');
            app.MainUIFigure.Name = 'Main';
            app.MainUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MainUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.OrderTab = uitab(app.TabGroup);
            app.OrderTab.Title = 'Order';
            app.UIAxes = uiaxes(app.OrderTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 1];
            app.UIAxes.YTickLabel = '';
            app.STARTButton_2 = uibutton(app.OrderTab, 'push');
            app.STARTButton_2.ButtonPushedFcn = createCallbackFcn(app, @STARTButton_2Pushed, true);
            app.STARTButton_2.Text = 'START';
            app.InkPrintingLampLabel = uilabel(app.OrderTab);
            app.InkPrintingLampLabel.Text = 'Ink Printing';
            app.InkPrintingLamp = uilamp(app.OrderTab);
            app.CakeDecorationLampLabel = uilabel(app.OrderTab);
            app.CakeDecorationLampLabel.Text = 'Cake Decoration';
            app.CakeDecorationLamp = uilamp(app.OrderTab);
            app.TextArea_2 = uitextarea(app.OrderTab);
            app.UIAxes_2 = uiaxes(app.OrderTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [0 1];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [0 1];
            app.UIAxes_2.YTickLabel = '';
            app.ErrorLampLabel = uilabel(app.OrderTab);
            app.ErrorLampLabel.Text = 'Error';
            app.ErrorLamp = uilamp(app.OrderTab);
            app.SPEEDLabel = uilabel(app.OrderTab);
            app.SPEEDLabel.Text = 'SPEED:';
            app.mmsLabel = uilabel(app.OrderTab);
            app.mmsLabel.Text = '0 mm/s';
            app.DemoTab = uitab(app.TabGroup);
            app.DemoTab.Title = 'Demo';
            app.TableCamera = uiaxes(app.DemoTab);
            title(app.TableCamera, '')
            xlabel(app.TableCamera, '')
            ylabel(app.TableCamera, {''; ''})
            app.TableCamera.XTick = [0 1];
            app.TableCamera.XTickLabel = '';
            app.TableCamera.YTick = [0 1];
            app.TableCamera.YTickLabel = '';
            app.ConveyorCamera = uiaxes(app.DemoTab);
            title(app.ConveyorCamera, '')
            xlabel(app.ConveyorCamera, '')
            ylabel(app.ConveyorCamera, '')
            app.ConveyorCamera.XTick = [0 1];
            app.ConveyorCamera.XTickLabel = '';
            app.ConveyorCamera.YTick = [0 1];
            app.ConveyorCamera.YTickLabel = '';
            app.Notifications = uipanel(app.DemoTab);
            app.Notifications.Title = 'Notifications';
            app.LampLabel = uilabel(app.Notifications);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.Notifications);
            app.Lamp2Label = uilabel(app.Notifications);
            app.Lamp2Label.Text = 'Lamp2';
            app.Lamp2 = uilamp(app.Notifications);
            app.Lamp3Label = uilabel(app.Notifications);
            app.Lamp3Label.Text = 'Lamp3';
            app.Lamp3 = uilamp(app.Notifications);
            app.Lamp4Label = uilabel(app.Notifications);
            app.Lamp4Label.Text = 'Lamp4';
            app.Lamp4 = uilamp(app.Notifications);
            app.Lamp5Label = uilabel(app.Notifications);
            app.Lamp5Label.Text = 'Lamp5';
            app.Lamp5 = uilamp(app.Notifications);
            app.Lamp6Label = uilabel(app.Notifications);
            app.Lamp6Label.Text = 'Lamp6';
            app.Lamp6 = uilamp(app.Notifications);
            app.Controls = uipanel(app.DemoTab);
            app.Controls.Title = 'Controls';
            app.VacuumLabel = uilabel(app.Controls);
            app.VacuumLabel.Text = 'Vacuum';
            app.ConveyorLabel = uilabel(app.Controls);
            app.ConveyorLabel.Text = 'Conveyor';
            app.ConveyorDirectionButtonGroup = uibuttongroup(app.Controls);
            app.ConveyorDirectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConveyorDirectionButtonGroupSelectionChanged, true);
            app.ConveyorDirectionButtonGroup.Title = 'Conveyor Direction';
            app.TowardsButton = uiradiobutton(app.ConveyorDirectionButtonGroup);
            app.TowardsButton.Text = 'Towards';
            app.TowardsButton.Value = true;
            app.AwayButton = uiradiobutton(app.ConveyorDirectionButtonGroup);
            app.AwayButton.Text = 'Away';
            app.SOLENOIDButton = uibutton(app.Controls, 'push');
            app.SOLENOIDButton.Text = 'SOLENOID';
            app.STARTButton = uibutton(app.Controls, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.SolenoidButtonGroup = uibuttongroup(app.Controls);
            app.SolenoidButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolenoidButtonGroupSelectionChanged, true);
            app.SolenoidButtonGroup.Title = 'Solenoid';
            app.OffButton = uiradiobutton(app.SolenoidButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.OnButton = uiradiobutton(app.SolenoidButtonGroup);
            app.OnButton.Text = {'On'; ''};
            app.POWERButton_2 = uibutton(app.Controls, 'state');
            app.POWERButton_2.ValueChangedFcn = createCallbackFcn(app, @POWERButton_2ValueChanged, true);
            app.POWERButton_2.Text = 'POWER';
            app.ConsolePanel = uipanel(app.DemoTab);
            app.ConsolePanel.Title = 'Console';
            app.TextArea = uitextarea(app.ConsolePanel);
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.TableCameraPanel = uipanel(app.CameraTab);
            app.TableCameraPanel.Title = 'Table Camera';
            app.TCam = uiaxes(app.TableCameraPanel);
            title(app.TCam, '')
            xlabel(app.TCam, '')
            ylabel(app.TCam, '')
            app.TCam.XTick = [0 1];
            app.TCam.XTickLabel = '';
            app.TCam.YTick = [0 1];
            app.TCam.YTickLabel = '';
            app.ConveyorCameraPanel = uipanel(app.CameraTab);
            app.ConveyorCameraPanel.Title = 'Conveyor Camera';
            app.CCam = uiaxes(app.ConveyorCameraPanel);
            title(app.CCam, '')
            xlabel(app.CCam, '')
            ylabel(app.CCam, '')
            app.CCam.XTick = [0 1];
            app.CCam.XTickLabel = '';
            app.CCam.YTick = [0 1];
            app.CCam.YTickLabel = '';
            app.Status = uilamp(app.MainUIFigure);
            app.Knob = uiknob(app.MainUIFigure, 'discrete');
            app.Knob.Items = {'OFF', 'PAUSE', 'ON'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'ON';
            app.ROBOTSTATELabel = uilabel(app.MainUIFigure);
            app.ROBOTSTATELabel.Text = 'ROBOT STATE:';
            app.TASKLabel = uilabel(app.MainUIFigure);
            app.TASKLabel.Text = 'TASK:';
            app.CONNECTIONLabel = uilabel(app.MainUIFigure);
            app.CONNECTIONLabel.Text = 'CONNECTION:';
            app.DISCONNECTEDLabel = uilabel(app.MainUIFigure);
            app.DISCONNECTEDLabel.Text = 'DISCONNECTED';
            app.IDLELabel = uilabel(app.MainUIFigure);
            app.IDLELabel.Text = 'IDLE';
            app.WAITINGLabel = uilabel(app.MainUIFigure);
            app.WAITINGLabel.Text = 'WAITING';
            % Show the figure after all components are created
            app.MainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end